classdef LSystemApp2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        LeftPanel                    matlab.ui.container.Panel
        ClearInputButton             matlab.ui.control.Button
        DOnlyLabel                   matlab.ui.control.Label
        SpatialAngleSlider           matlab.ui.control.Slider
        SpatialAngleSliderLabel      matlab.ui.control.Label
        SpatialAngleTextArea         matlab.ui.control.TextArea
        DimSwitch                    matlab.ui.control.Switch
        GenerateButton               matlab.ui.control.Button
        Rule3fEditField              matlab.ui.control.EditField
        Rule3iEditField              matlab.ui.control.EditField
        Rule3Label                   matlab.ui.control.Label
        Rule2fEditField              matlab.ui.control.EditField
        Rule2iEditField              matlab.ui.control.EditField
        Rule2Label                   matlab.ui.control.Label
        Rule1fEditField              matlab.ui.control.EditField
        Rule1iEditField              matlab.ui.control.EditField
        Rule1EditFieldLabel          matlab.ui.control.Label
        AxiomEditField               matlab.ui.control.EditField
        AxiomEditFieldLabel          matlab.ui.control.Label
        IterationsTextArea           matlab.ui.control.TextArea
        ReductionFactorTextArea      matlab.ui.control.TextArea
        RotationAngleTextArea        matlab.ui.control.TextArea
        SaveResultButton             matlab.ui.control.Button
        GenerateyourownLSystemLabel  matlab.ui.control.Label
        Version2NirAlmogLabel        matlab.ui.control.Label
        LindenmayerSystemGeneratorLabel  matlab.ui.control.Label
        ReductionFactorSlider        matlab.ui.control.Slider
        ReductionFactorSliderLabel   matlab.ui.control.Label
        RotationAngleSlider          matlab.ui.control.Slider
        RotationAngleLabel           matlab.ui.control.Label
        IterationsSlider             matlab.ui.control.Slider
        IterationsSliderLabel        matlab.ui.control.Label
        RightPanel                   matlab.ui.container.Panel
        TipsFMoveForwardClockwiseRotationAntiClockwiseRotationLabel_2  matlab.ui.control.Label
        TipsFMoveForwardClockwiseRotationAntiClockwiseRotationLabel  matlab.ui.control.Label
        UIAxes                       matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: IterationsSlider
        function IterationsSliderValueChanged(app, event)
            p.iterations  = round(app.IterationsSlider.Value);
            app.IterationsTextArea.Value = num2str(p.iterations);
        end

        % Value changed function: RotationAngleSlider
        function RotationAngleSliderValueChanged(app, event)
            p.angle = round(app.RotationAngleSlider.Value * 1) / 1;
            app.RotationAngleTextArea.Value = num2str(p.angle);
        end

        % Value changed function: ReductionFactorSlider
        function ReductionFactorSliderValueChanged(app, event)
            p.reductionFactor = round(app.ReductionFactorSlider.Value * 1000) / 1000;
            app.ReductionFactorTextArea.Value = num2str(p.reductionFactor);
        end

        % Value changed function: SpatialAngleSlider
        function SpatialAngleSliderValueChanged(app, event)
            p.spatialAngle = round(app.SpatialAngleSlider.Value * 1) / 1;
            app.SpatialAngleTextArea.Value = num2str(p.spatialAngle);
        end

        % Value changed function: IterationsTextArea
        function IterationsTextAreaValueChanged(app, event)
            % Get the current value of the text box
            IterationstextBoxValue = str2double(app.IterationsTextArea.Value);
    
            % Check if the input is a valid number
            if isnan(IterationstextBoxValue)
                % If the input is not a valid number, set it to the minimum value of the slider
                IterationstextBoxValue = app.IterationsSlider.Limits(1);
            else
                % Ensure the new value is within the bounds of the slider
                IterationstextBoxValue = max(app.IterationsSlider.Limits(1), min(IterationstextBoxValue, app.IterationsSlider.Limits(2)));
            end
    
            % Update the value of the slider
            app.IterationsSlider.Value = IterationstextBoxValue;
    
            % Update the value of the text box to reflect the final value (in case the input was out of bounds)
            app.IterationsTextArea.Value = num2str(IterationstextBoxValue);
        end

        % Value changed function: RotationAngleTextArea
        function RotationAngleTextAreaValueChanged(app, event)
            % Get the current value of the text box
            RotationtextBoxValue = str2double(app.RotationAngleTextArea.Value);
    
            % Check if the input is a valid number
            if isnan(RotationtextBoxValue)
                % If the input is not a valid number, set it to the minimum value of the slider
                RotationtextBoxValue = app.RotationAngleSlider.Limits(1);
            else
                % Ensure the new value is within the bounds of the slider
                RotationtextBoxValue = max(app.RotationAngleSlider.Limits(1), min(RotationtextBoxValue, app.RotationAngleSlider.Limits(2)));
            end
    
            % Update the value of the slider
            app.RotationAngleSlider.Value = RotationtextBoxValue;
    
            % Update the value of the text box to reflect the final value (in case the input was out of bounds)
            app.RotationAngleTextArea.Value = num2str(RotationtextBoxValue);
        end

        % Value changed function: SpatialAngleTextArea
        function SpatialAngleTextAreaValueChanged(app, event)
            % Get the current value of the text box
            SpatialtextBoxValue = str2double(app.SpatialAngleTextArea.Value);
    
            % Check if the input is a valid number
            if isnan(SpatialtextBoxValue)
                % If the input is not a valid number, set it to the minimum value of the slider
                SpatialtextBoxValue = app.SpatialAngleSlider.Limits(1);
            else
                % Ensure the new value is within the bounds of the slider
                SpatialtextBoxValue = max(app.SpatialAngleSlider.Limits(1), min(SpatialtextBoxValue, app.SpatialAngleSlider.Limits(2)));
            end
    
            % Update the value of the slider
            app.SpatialAngleSlider.Value = SpatialtextBoxValue;
    
            % Update the value of the text box to reflect the final value (in case the input was out of bounds)
            app.SpatialAngleTextArea.Value = num2str(SpatialtextBoxValue);
        end

        % Value changed function: ReductionFactorTextArea
        function ReductionFactorTextAreaValueChanged(app, event)
            % Get the current value of the text box
            ReductionFactortextBoxValue = str2double(app.ReductionFactorTextArea.Value);
    
            % Check if the input is a valid number
            if isnan(ReductionFactortextBoxValue)
                % If the input is not a valid number, set it to the minimum value of the slider
                ReductionFactortextBoxValue = app.ReductionFactorSlider.Limits(1);
            else
                % Ensure the new value is within the bounds of the slider
                ReductionFactortextBoxValue = max(app.ReductionFactorSlider.Limits(1), min(ReductionFactortextBoxValue, app.ReductionFactorSlider.Limits(2)));
            end
    
            % Update the value of the slider
            app.ReductionFactorSlider.Value = ReductionFactortextBoxValue;
    
            % Update the value of the text box to reflect the final value (in case the input was out of bounds)
            app.ReductionFactorTextArea.Value = num2str(ReductionFactortextBoxValue);
        end

        % Button pushed function: ClearInputButton
        function ClearInputButtonPushed(app, event)
            app.IterationsSlider.Value = 1;
            app.IterationsTextArea.Value = num2str(app.IterationsSlider.Value);
            
            app.RotationAngleSlider.Value = 0;
            app.RotationAngleTextArea.Value = num2str(app.RotationAngleSlider.Value);
            
            app.ReductionFactorSlider.Value = 1;
            app.ReductionFactorTextArea.Value = num2str(app.ReductionFactorSlider.Value);
            
            app.SpatialAngleSlider.Value = 0;
            app.SpatialAngleTextArea.Value = num2str(app.SpatialAngleSlider.Value);
            
            app.AxiomEditField.Value = '';
            app.Rule1iEditField.Value = '';
            app.Rule1fEditField.Value = '';
            app.Rule2iEditField.Value = '';
            app.Rule2fEditField.Value = '';
            app.Rule3iEditField.Value = '';
            app.Rule3fEditField.Value = '';
            
            app.AxiomEditField.Placeholder = '';
            app.Rule1iEditField.Placeholder = '';
            app.Rule1fEditField.Placeholder = '';

        end

        % Button pushed function: GenerateButton
        function GenerateButtonPushed(app, event)
            p.initialLength = 1;
            p.iterations  = round(app.IterationsSlider.Value);
            p.angle = round(app.RotationAngleSlider.Value * 1) / 1;
            p.reductionFactor = round(app.ReductionFactorSlider.Value * 1000) / 1000;
            p.axiom = app.AxiomEditField.Value;
            p.spatialAngle = round(app.SpatialAngleSlider.Value * 1) / 1;
            
            emptyCellArray = cell(3, 2);
            emptyCellArray{1, 1} = app.Rule1iEditField.Value;
            emptyCellArray{1, 2} = app.Rule1fEditField.Value;
            emptyCellArray{2, 1} = app.Rule2iEditField.Value;
            emptyCellArray{2, 2} = app.Rule2fEditField.Value;
            emptyCellArray{3, 1} = app.Rule3iEditField.Value;
            emptyCellArray{3, 2} = app.Rule3fEditField.Value;
            p.rules = emptyCellArray;
            SwitchState = app.DimSwitch.Value;
            
            reset(app.UIAxes);
            
            if SwitchState == '2D'
                points = LSystemTree(p);
                darkBackground = rand(1, 3) * 0.2;
                brightColor = rand(1, 3) * 0.6 + 0.4;
                plot(app.UIAxes, points(:, 1), points(:, 2), 'Color', brightColor, 'LineWidth', 1.5);
                set(app.UIAxes, 'Color', darkBackground);
                rotate3d(app.UIAxes, 'off');
                view(app.UIAxes, 2);
                axis(app.UIAxes, 'equal');
                app.UIAxes.XTickLabel = [];
                app.UIAxes.YTickLabel = [];
                app.UIAxes.XTick = [];
                app.UIAxes.YTick = [];
                aspectRatioValue = 1; 
                app.UIAxes.DataAspectRatio = [aspectRatioValue, aspectRatioValue, aspectRatioValue];
            elseif SwitchState == '3D'
                points = LSystemTree3D(p);
                darkBackground = rand(1, 3) * 0.2;
                brightColor = rand(1, 3) * 0.6 + 0.4;
                plot3(app.UIAxes, points(:, 1), points(:, 2), points(:, 3), 'Color', brightColor, 'LineWidth', 1.5);
                set(app.UIAxes, 'Color', darkBackground);
                axis(app.UIAxes, 'equal');
                %axis off;
                view(app.UIAxes, 3);
                %view(app.UIAxes,3); % Set the 3D view
                rotate3d(app.UIAxes, 'on');
                app.UIAxes.XTickLabel = [];
                app.UIAxes.YTickLabel = [];
                app.UIAxes.ZTickLabel = [];
                app.UIAxes.XTick = [];
                app.UIAxes.YTick = [];
                app.UIAxes.ZTick = [];
                aspectRatioValue = 1; 
                app.UIAxes.DataAspectRatio = [aspectRatioValue, aspectRatioValue, aspectRatioValue];

            end
        end

        % Button pushed function: SaveResultButton
        function SaveResultButtonPushed(app, event)
            d.spatialAngle = round(app.SpatialAngleSlider.Value * 1) / 1;
            d.iterations  = round(app.IterationsSlider.Value);
            d.angle = round(app.RotationAngleSlider.Value * 1) / 1;
            d.reductionFactor = round(app.ReductionFactorSlider.Value * 1000) / 1000;
            d.axiom = app.AxiomEditField.Value;
            
            emptyCellArray = cell(3, 2);
            emptyCellArray{1, 1} = app.Rule1iEditField.Value;
            emptyCellArray{1, 2} = app.Rule1fEditField.Value;
            emptyCellArray{2, 1} = app.Rule2iEditField.Value;
            emptyCellArray{2, 2} = app.Rule2fEditField.Value;
            emptyCellArray{3, 1} = app.Rule3iEditField.Value;
            emptyCellArray{3, 2} = app.Rule3fEditField.Value;
            d.rules = emptyCellArray;
            
            Note = ' | Dimensions | ' + string(app.DimSwitch.Value) + ...
                   ' | Axiom | ' + string(d.axiom) + ...
                   ' | Angle | ' + num2str(d.angle) + ...
                   ' | Spatial Angle | ' + num2str(d.spatialAngle) + ...
                   ' | Iterations | ' + num2str(d.iterations) + ...
                   ' | Rule1 | ' + string(d.rules{1,1}) + ' | ' + string(d.rules{1,2}) + ...
                   ' | Rule2 | ' + string(d.rules{2,1}) + ' | ' + string(d.rules{2,2}) + ...
                   ' | Rule3 | ' + string(d.rules{3,1}) + ' | ' + string(d.rules{3,2});

            
            % Get the current date and time
            currentTime = clock;
            dateStr = datestr(currentTime, 'yyyymmdd_HHMMSS');
        
            % Save the current figure displayed in the UI Axis as a PNG image with the date and time as the file name
            fileName = [dateStr, '_figure.png'];
            exportgraphics(app.UIAxes, fileName, 'Resolution', 300);
        
            % Save a note file with the same name (date and time) and some text content
            noteFileName = [dateStr, '_note.txt'];
            noteContent = Note;
            fid = fopen(noteFileName, 'wt');
            fprintf(fid, '%s\n\r', noteContent);
            fclose(fid);
        end

        % Value changed function: DimSwitch
        function DimSwitchValueChanged(app, event)
            
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {448, 448};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {349, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.IterationsSliderLabel = uilabel(app.LeftPanel);
            app.IterationsSliderLabel.Text = 'Iterations';
            app.IterationsSlider = uislider(app.LeftPanel);
            app.IterationsSlider.Limits = [1 5];
            app.IterationsSlider.MajorTicks = [1 2 3 4 5];
            app.IterationsSlider.ValueChangedFcn = createCallbackFcn(app, @IterationsSliderValueChanged, true);
            app.IterationsSlider.MinorTicks = [1 2 3 4 5];
            app.IterationsSlider.Value = 4;
            app.RotationAngleLabel = uilabel(app.LeftPanel);
            app.RotationAngleLabel.Text = {'Rotation'; 'Angle'};
            app.RotationAngleSlider = uislider(app.LeftPanel);
            app.RotationAngleSlider.Limits = [0 180];
            app.RotationAngleSlider.MajorTicks = [0 30 60 90 120 150 180];
            app.RotationAngleSlider.ValueChangedFcn = createCallbackFcn(app, @RotationAngleSliderValueChanged, true);
            app.RotationAngleSlider.MinorTicks = [0 10 20 30 40 50 60 70 80 90 100 110 120 130 140 150 160 170 180];
            app.RotationAngleSlider.Value = 60;
            app.ReductionFactorSliderLabel = uilabel(app.LeftPanel);
            app.ReductionFactorSliderLabel.Text = {'Reduction'; 'Factor'};
            app.ReductionFactorSlider = uislider(app.LeftPanel);
            app.ReductionFactorSlider.Limits = [0.99 1];
            app.ReductionFactorSlider.MajorTicks = [0.99 0.999];
            app.ReductionFactorSlider.MajorTickLabels = {'0.99', '0.999'};
            app.ReductionFactorSlider.ValueChangedFcn = createCallbackFcn(app, @ReductionFactorSliderValueChanged, true);
            app.ReductionFactorSlider.MinorTicks = [0.99 0.9903 0.9906 0.9909 0.9912 0.9915 0.9918 0.9921 0.9924 0.9927 0.993 0.9933 0.9936 0.9939 0.9942 0.9945 0.9948 0.9951 0.9954 0.9957 0.996 0.9963 0.9966 0.9969 0.9972 0.9975 0.9978 0.9981 0.9984 0.9987 0.999 0.9993 0.9996 1];
            app.ReductionFactorSlider.Value = 1;
            app.LindenmayerSystemGeneratorLabel = uilabel(app.LeftPanel);
            app.LindenmayerSystemGeneratorLabel.Text = 'Lindenmayer System Generator';
            app.Version2NirAlmogLabel = uilabel(app.LeftPanel);
            app.Version2NirAlmogLabel.Text = {'Version 2'; 'Nir Almog'};
            app.GenerateyourownLSystemLabel = uilabel(app.LeftPanel);
            app.GenerateyourownLSystemLabel.Text = 'Generate your own L-System!';
            app.SaveResultButton = uibutton(app.LeftPanel, 'push');
            app.SaveResultButton.ButtonPushedFcn = createCallbackFcn(app, @SaveResultButtonPushed, true);
            app.SaveResultButton.Text = 'Save Result';
            app.RotationAngleTextArea = uitextarea(app.LeftPanel);
            app.RotationAngleTextArea.ValueChangedFcn = createCallbackFcn(app, @RotationAngleTextAreaValueChanged, true);
            app.RotationAngleTextArea.Placeholder = '60';
            app.RotationAngleTextArea.Value = {'60'};
            app.ReductionFactorTextArea = uitextarea(app.LeftPanel);
            app.ReductionFactorTextArea.ValueChangedFcn = createCallbackFcn(app, @ReductionFactorTextAreaValueChanged, true);
            app.ReductionFactorTextArea.Placeholder = '1';
            app.ReductionFactorTextArea.Value = {'1'};
            app.IterationsTextArea = uitextarea(app.LeftPanel);
            app.IterationsTextArea.ValueChangedFcn = createCallbackFcn(app, @IterationsTextAreaValueChanged, true);
            app.IterationsTextArea.Placeholder = '4';
            app.IterationsTextArea.Value = {'4'};
            app.AxiomEditFieldLabel = uilabel(app.LeftPanel);
            app.AxiomEditFieldLabel.Text = 'Axiom';
            app.AxiomEditField = uieditfield(app.LeftPanel, 'text');
            app.AxiomEditField.Placeholder = 'F';
            app.AxiomEditField.Value = 'F';
            app.Rule1EditFieldLabel = uilabel(app.LeftPanel);
            app.Rule1EditFieldLabel.Text = 'Rule 1';
            app.Rule1iEditField = uieditfield(app.LeftPanel, 'text');
            app.Rule1iEditField.Placeholder = 'F';
            app.Rule1iEditField.Value = 'F';
            app.Rule1fEditField = uieditfield(app.LeftPanel, 'text');
            app.Rule1fEditField.Placeholder = 'F+F-F-F+F';
            app.Rule1fEditField.Value = 'F+F-F-F+F';
            app.Rule2Label = uilabel(app.LeftPanel);
            app.Rule2Label.Text = 'Rule 2';
            app.Rule2iEditField = uieditfield(app.LeftPanel, 'text');
            app.Rule2fEditField = uieditfield(app.LeftPanel, 'text');
            app.Rule3Label = uilabel(app.LeftPanel);
            app.Rule3Label.Text = 'Rule 3';
            app.Rule3iEditField = uieditfield(app.LeftPanel, 'text');
            app.Rule3fEditField = uieditfield(app.LeftPanel, 'text');
            app.GenerateButton = uibutton(app.LeftPanel, 'push');
            app.GenerateButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateButtonPushed, true);
            app.GenerateButton.Text = 'Generate';
            app.DimSwitch = uiswitch(app.LeftPanel, 'slider');
            app.DimSwitch.Items = {'2D', '3D'};
            app.DimSwitch.ValueChangedFcn = createCallbackFcn(app, @DimSwitchValueChanged, true);
            app.DimSwitch.Value = '2D';
            app.SpatialAngleTextArea = uitextarea(app.LeftPanel);
            app.SpatialAngleTextArea.ValueChangedFcn = createCallbackFcn(app, @SpatialAngleTextAreaValueChanged, true);
            app.SpatialAngleTextArea.Placeholder = '60';
            app.SpatialAngleTextArea.Value = {'60'};
            app.SpatialAngleSliderLabel = uilabel(app.LeftPanel);
            app.SpatialAngleSliderLabel.Text = {'Spatial'; 'Angle'};
            app.SpatialAngleSlider = uislider(app.LeftPanel);
            app.SpatialAngleSlider.Limits = [0 180];
            app.SpatialAngleSlider.MajorTicks = [0 30 60 90 120 150 180];
            app.SpatialAngleSlider.ValueChangedFcn = createCallbackFcn(app, @SpatialAngleSliderValueChanged, true);
            app.SpatialAngleSlider.MinorTicks = [0 10 20 30 40 50 60 70 80 90 100 110 120 130 140 150 160 170 180];
            app.SpatialAngleSlider.Value = 60;
            app.DOnlyLabel = uilabel(app.LeftPanel);
            app.DOnlyLabel.Text = '*3D Only';
            app.ClearInputButton = uibutton(app.LeftPanel, 'push');
            app.ClearInputButton.ButtonPushedFcn = createCallbackFcn(app, @ClearInputButtonPushed, true);
            app.ClearInputButton.Text = 'Clear Input';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.TipsFMoveForwardClockwiseRotationAntiClockwiseRotationLabel = uilabel(app.RightPanel);
            app.TipsFMoveForwardClockwiseRotationAntiClockwiseRotationLabel.Text = {'Generation Tips:'; '''F''  = Move Forward'; '''+'' = Clockwise Rotation'; '''-''  = Anti-Clockwise Rotation'; '''[''  = Begin Stacking'};
            app.TipsFMoveForwardClockwiseRotationAntiClockwiseRotationLabel_2 = uilabel(app.RightPanel);
            app.TipsFMoveForwardClockwiseRotationAntiClockwiseRotationLabel_2.Text = {''']''  = End Stacking'; '''^'' = Pitch Up'; '''&'' = Pitch Down'; '''/''  = Roll Right'; '''\''  = Roll Left'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LSystemApp2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end